classdef peakfileter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        rtcolEditField         matlab.ui.control.NumericEditField
        rtcolEditFieldLabel    matlab.ui.control.Label
        mzcolEditField         matlab.ui.control.NumericEditField
        mzcolEditFieldLabel    matlab.ui.control.Label
        UITable                matlab.ui.control.Table
        CNNModelDropDown       matlab.ui.control.DropDown
        CNNModelDropDownLabel  matlab.ui.control.Label
        AveEditField           matlab.ui.control.NumericEditField
        AveEditFieldLabel      matlab.ui.control.Label
        ppmEditField           matlab.ui.control.NumericEditField
        ppmEditFieldLabel      matlab.ui.control.Label
        RunButton              matlab.ui.control.Button
        LoadpeakTableButton    matlab.ui.control.Button
        LoadmzXMLButton        matlab.ui.control.Button
        RightPanel             matlab.ui.container.Panel
        Label                  matlab.ui.control.Label
        TRUELamp               matlab.ui.control.Lamp
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
       fn_mzXML 
       fn_pklist
       ispeak
       img
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadmzXMLButton
        function LoadmzXMLButtonPushed(app, event)
            [file,path]=uigetfile('*.mzXML');
            app.fn_mzXML=fullfile(path,file);
        end

        % Button pushed function: LoadpeakTableButton
        function LoadpeakTableButtonPushed(app, event)
            [file,path]=uigetfile('*.csv');
            app.fn_pklist=fullfile(path,file);
            T=readtable(app.fn_pklist);
            app.UITable.Data=T;
            app.UITable.ColumnName=T.Properties.VariableNames;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            ppm=app.ppmEditField.Value;
            ave=app.AveEditField.Value;
            cols=[app.mzcolEditField.Value,app.rtcolEditField.Value];
            [app.ispeak,app.img]=peaksEVA(app.fn_mzXML,app.fn_pklist,cols,app.CNNModelDropDown.Value,ppm,ave); 
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            id=indices(1);
            if ~isempty(app.img)
            imshow(app.img{id}.cdata,'parent',app.UIAxes);
            if app.ispeak(id)
              app.TRUELamp.Color='g';
              app.Label.Text='TRUE';
            else
              app.TRUELamp.Color='r';
              app.Label.Text='FALSE';
            end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {205, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadmzXMLButton = uibutton(app.LeftPanel, 'push');
            app.LoadmzXMLButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmzXMLButtonPushed, true);
            app.LoadmzXMLButton.Text = 'Load mzXML';
            app.LoadpeakTableButton = uibutton(app.LeftPanel, 'push');
            app.LoadpeakTableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpeakTableButtonPushed, true);
            app.LoadpeakTableButton.Text = 'Load peakTable';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ppmEditFieldLabel = uilabel(app.LeftPanel);
            app.ppmEditFieldLabel.Text = 'ppm';
            app.ppmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ppmEditField.Value = 5;
            app.AveEditFieldLabel = uilabel(app.LeftPanel);
            app.AveEditFieldLabel.Text = 'Ave';
            app.AveEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AveEditField.Value = 5;
            app.CNNModelDropDownLabel = uilabel(app.LeftPanel);
            app.CNNModelDropDownLabel.Text = 'CNN Model';
            app.CNNModelDropDown = uidropdown(app.LeftPanel);
            app.CNNModelDropDown.Items = {'net48', 'net64'};
            app.CNNModelDropDown.Value = 'net48';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.mzcolEditFieldLabel = uilabel(app.LeftPanel);
            app.mzcolEditFieldLabel.Text = 'm/z col ';
            app.mzcolEditField = uieditfield(app.LeftPanel, 'numeric');
            app.mzcolEditField.Value = 5;
            app.rtcolEditFieldLabel = uilabel(app.LeftPanel);
            app.rtcolEditFieldLabel.Text = 'rt col ';
            app.rtcolEditField = uieditfield(app.LeftPanel, 'numeric');
            app.rtcolEditField.Value = 6;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.TRUELamp = uilamp(app.RightPanel);
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = 'TRUE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = peakfileter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end